classdef QE_app_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportDataTab                   matlab.ui.container.Tab
        LoadCCDCameraResponsivityDataButton  matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        LoadEmissionBackgroundDataButton  matlab.ui.control.Button
        LoadEmissionDataButton          matlab.ui.control.Button
        LoadPMTResponsivityDataButton   matlab.ui.control.Button
        LoadCVBackgroundDataButton      matlab.ui.control.Button
        LoadCVDataButton                matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        LoadPulsingBackgroundButton     matlab.ui.control.Button
        LoadPulsingDataButton           matlab.ui.control.Button
        ValuesTab                       matlab.ui.container.Tab
        CalculateCVCurrentCorrectionFactorButton  matlab.ui.control.Button
        CVCurrentCorrectionFactorEditField  matlab.ui.control.NumericEditField
        CVCurrentCorrectionFactorEditFieldLabel  matlab.ui.control.Label
        PMTGainFactorEditField          matlab.ui.control.NumericEditField
        PMTGainFactorEditFieldLabel     matlab.ui.control.Label
        ElectrodeDistancefromPMTmEditField  matlab.ui.control.NumericEditField
        ElectrodeDistancefromPMTmEditFieldLabel  matlab.ui.control.Label
        PMTSurfaceaream2EditField       matlab.ui.control.NumericEditField
        PMTSurfaceaream2EditFieldLabel  matlab.ui.control.Label
        ElectrodeReflatMaxWaveEditField  matlab.ui.control.NumericEditField
        ElectrodeReflatMaxWaveEditFieldLabel  matlab.ui.control.Label
        CFactorEditField                matlab.ui.control.NumericEditField
        CFactorEditFieldLabel           matlab.ui.control.Label
        CalculateCFactorButton          matlab.ui.control.Button
        AbsorptionCorrectionEditField   matlab.ui.control.NumericEditField
        AbsorptionCorrectionEditFieldLabel  matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        CalculatePulsingCurrentCorrectionFactorButton  matlab.ui.control.Button
        PulsingCurrentCorrectionFactorEditField  matlab.ui.control.NumericEditField
        PulsingCurrentCorrectionFactorEditFieldLabel  matlab.ui.control.Label
        PMTCalibFactorEditField         matlab.ui.control.NumericEditField
        PMTCalibFactorEditFieldLabel    matlab.ui.control.Label
        PMTSensnAVEditField             matlab.ui.control.NumericEditField
        PMTSensnAVEditFieldLabel        matlab.ui.control.Label
        ElectrodeDiametermEditField     matlab.ui.control.NumericEditField
        ElectrodeDiametermEditFieldLabel  matlab.ui.control.Label
        OpticalDensityEditField         matlab.ui.control.NumericEditField
        OpticalDensityEditFieldLabel    matlab.ui.control.Label
        QECalcTab                       matlab.ui.container.Tab
        TextArea_5                      matlab.ui.control.TextArea
        QECVValueEditField              matlab.ui.control.NumericEditField
        QECVValueEditFieldLabel         matlab.ui.control.Label
        QECVCalculationButton           matlab.ui.control.Button
        QEPulsingValueEditField         matlab.ui.control.NumericEditField
        QEPulsingValueEditFieldLabel    matlab.ui.control.Label
        QEPulsingCalculationButton      matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        %Scientific constants required
        
        planck = 6.6261e-34; %in J/s
        lightspeed = 299792458; %in m/s
        pi=3.14159264359;
        charge = 1.60217662e-19; %coulombs
        AtoEPSFactor = 6.242e18; %Amps to electrons per seconds
        
        %System dependent variables
        
        elecDiameter=0.003; %random setting, in m
        PMTSens=10000; %nA / V, normally is negative, but maybe not for 4687
        absorptionCorrectionFactor=1.1952; %See paper for description
        pulsingCurrentCorrectionFactor=(1-0.09); %Default setting
        CVCurrentCorrectionFactor=(1-0.50); %Default setting
        PMTCalibFactor=0.1732; %Use this if below 25 uA
        opticalDensityOfFilter=2;
        elecReflectivity=0.60;
        CFactor=0.1373;
        APMT=0.000192; %in mm^2
        dPMT=0.0716; %distance from elec surface to pmt in mm
        gainFactor=1.3e6; %Check your voltage applied to the PMT
        noiseValue=0.3;
        
        %Initializing important arrays
        
        pulsingData=1;
        pulsingBG=1;
        CVData=1;
        CVBG=1;
        PMTResponsivity=1;
        emission=1;
        emissionBG=1;
        CCDresponsivity=1;
        
        pulsingQE=1;
        CVQE=1;
        
        %Necessary calculations
        
%         AreaCorrectionFactor=1053.470558; %This will be for a sphere for ECL
        HValue = 1;
        Asource = 0.000001 %m^2 for Pt electrode
        ODFilterFactor=1;
        EPhoton = 8.983058349432325e-20; %joules/photon
        KFactor = 4.198728862684060e-17; %lm s/photon
        
        %Graph variables
        
        linewidth=3;
        fontsize=15;
        axisFontSize=10;
        tickLabelFontSize=12;
        axisLineThickness=2;
        ylabel="Total Photon Flux (photons/s)";
        xlabel="Time (s)";
        xlabel3="Voltage (V vs. Ref)";
        ylabel2=["Normalized Background";"Subtracted Emission Spectra"];
        xlabel2="Wavelength (nm)";
        ylabel3="PMT Responsivity (photons/electron)";
        ylabel4="Total Electron Flux (electrons/s)";
        tickPosition='in';
        fontType='Arial';
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPulsingDataButton
        function LoadPulsingDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.pulsingData = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadPulsingBackgroundButton
        function LoadPulsingBackgroundButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.pulsingBG = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadCVDataButton
        function LoadCVDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.CVData = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadCVBackgroundDataButton
        function LoadCVBackgroundDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.CVBG = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadPMTResponsivityDataButton
        function LoadPMTResponsivityDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.PMTResponsivity = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Value changed function: OpticalDensityEditField
        function OpticalDensityEditFieldValueChanged(app, event)
            app.opticalDensityOfFilter = app.OpticalDensityEditField.Value;
            app.ODFilterFactor=1/(10^-(app.opticalDensityOfFilter));
        end

        % Value changed function: ElectrodeDiametermEditField
        function ElectrodeDiametermEditFieldValueChanged(app, event)
            app.elecDiameter = app.ElectrodeDiametermEditField.Value;
            app.Asource = ((app.elecDiameter/2)^2)*app.pi;
        end

        % Value changed function: PMTSensnAVEditField
        function PMTSensnAVEditFieldValueChanged(app, event)
            app.PMTSens = app.PMTSensnAVEditField.Value;
            app.HValue = app.AtoEPSFactor*(app.PMTSens*1e-9); %Convert nA to A
        end

        % Value changed function: PulsingCurrentCorrectionFactorEditField
        function PulsingCurrentCorrectionFactorEditFieldValueChanged(app, event)
            app.pulsingCurrentCorrectionFactor = app.PulsingCurrentCorrectionFactorEditField.Value;
        end

        % Button pushed function: 
        % CalculatePulsingCurrentCorrectionFactorButton
        function CalculatePulsingCurrentCorrectionFactorButtonPushed(app, event)
            %Did you import data properly?
            
            if(iscell(app.pulsingData))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(app.pulsingData == 1)
                msgbox('You have not uploaded a corresponding file yet.','ERROR: NOVEMBER UNIFORM');
                return
            end
            if(iscell(app.pulsingBG))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(app.pulsingBG == 1)
                msgbox('You have not uploaded a corresponding file yet.','ERROR: NOVEMBER UNIFORM');
                return
            end


            %Get data necessary
            
            current=app.pulsingData(:,5);
            time=app.pulsingData(:,2);
            currentBG=app.pulsingBG(:,5);
            timeBG=app.pulsingBG(:,2);


            %Perform data operations
            
            current=abs(current);
            currentBG=abs(currentBG);
            chargee=cumtrapz(time,current);
            chargeeBG=cumtrapz(timeBG,currentBG);
            [m,~]=size(chargee);
            [mm,~]=size(chargeeBG);
            chargeeMax=chargee(m,1);
            chargeeBGMax=chargeeBG(mm,1);            
            app.pulsingCurrentCorrectionFactor=1-(chargeeBGMax/chargeeMax);


            %Final step
            app.PulsingCurrentCorrectionFactorEditField.Value = app.pulsingCurrentCorrectionFactor;
        end

        % Value changed function: PMTCalibFactorEditField
        function PMTCalibFactorEditFieldValueChanged(app, event)
            app.absorptionCorrectionFactor = app.PMTCalibFactorEditField.Value;
        end

        % Button pushed function: LoadEmissionDataButton
        function LoadEmissionDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.emission = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadEmissionBackgroundDataButton
        function LoadEmissionBackgroundDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.emissionBG = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Button pushed function: LoadCCDCameraResponsivityDataButton
        function LoadCCDCameraResponsivityDataButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.*',  'All Files (*.*)');
            oldFolder = cd(PathName);
            T=uiimport(FileName);
            
            [~,g] = size(FileName);
            TT=matlab.lang.makeValidName(FileName(1,(1:g-4))); %Converts file name to "useable" string
            TTT=getfield(T,TT);
            app.CCDresponsivity = TTT; %MUST SET THIS THE SAME OR RELOAD DATA
        end

        % Value changed function: CFactorEditField
        function CFactorEditFieldValueChanged(app, event)
            app.CFactor = app.CFactorEditField.Value;
        end

        % Button pushed function: CalculateCFactorButton
        function CalculateCFactorButtonPushed(app, event)
            %Don't run program if...
            
            if(app.CCDresponsivity==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(app.PMTResponsivity==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(app.emissionBG==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(app.emission==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(iscell(app.CCDresponsivity))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(iscell(app.PMTResponsivity))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(iscell(app.emissionBG))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(iscell(app.emission))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end


            %Check all important variables
            
            app.CFactor=app.CFactorEditField.Value;


            %BG subtract data from a blank scan
            
            BGSubSpectra=app.emission;
            BGSubSpectra(:,2)=abs(BGSubSpectra(:,2)-app.emissionBG(:,2));  
            workingFile = BGSubSpectra; %Must be 2 columns


            %Make sure responsivity is out of 1
            
            responsivity=app.PMTResponsivity;
            if(max(responsivity(:,2)) > 1.001)
                responsivity(:,2)=app.PMTResponsivity(:,2)./100;
            end


            %CCD factor correction
            
            xx=1;
            counter2=1;
            [m,~]=size(workingFile);
            [mm,~]=size(app.CCDresponsivity);
            flag2=0;
            
            while(xx<m)
                if(counter2 < mm)
                    while(workingFile(xx,1) < app.CCDresponsivity(counter2,1) && (counter2) < mm)
                        xx=xx+1;
                    end
                    if(counter2 >= (mm-1) && flag2<1)
                        flag2=xx;%This is where the correction ended
                    end
                    workingFile(xx,3) = workingFile(xx,2)*((1+((1-(app.CCDresponsivity(counter2,2)/100)))));
                    counter2=counter2+1;
                elseif(counter2>= mm)
                    xx=xx+1;
                end
            end


            %Emission normalization
            
            workingFile(:,4)=workingFile(:,3)./(max(workingFile(:,3)));
            
            %Remove noise

            xi=1;
            lastVal = workingFile(xi,4);
            [m,~] = size(workingFile);
    
            while(xi<=m)
        
                if((workingFile(xi,4)-lastVal) > 0.3)
                   workingFile(xi,4) = lastVal;
                else
                   workingFile(xi,4) = workingFile(xi,4);
                   lastVal = workingFile(xi,2);
                end
                xi=xi+1;
            end
            
            
            %%Interpolation!!
            %Changing the size of the responsivity data

            [mm,~]=size(responsivity);
            
            i=1;
            while(workingFile(i,1)<responsivity(1,1) && i<1023)
                i=i+1;
            end
            
            ii=i;
            while(workingFile(ii,1)<responsivity(mm,1) && i<1023)
                ii=ii+1;
            end
            ii=ii-1;
            
            %Interpdata
            interpResponsivity(:,1)=workingFile(i:ii,1);
            interpResponsivity(:,2)=(interp1(responsivity(:,1),responsivity(:,2),workingFile(i:ii,1)));
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Need to do the "C" function here%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [mmmm,~]=size(interpResponsivity);
            bottomCValueArray=cumtrapz(workingFile(i:ii,1),workingFile(i:ii,4));
            bottomCValue=bottomCValueArray(mmmm,1);
            topCValueArray=workingFile(i:ii,4).*interpResponsivity(:,2);
            topCValueArray(:,2)=cumtrapz(interpResponsivity(:,1),topCValueArray(:,1));
            topCValue=topCValueArray(mmmm,2);
            app.CFactor=(topCValue/bottomCValue);
            msgbox(num2str(app.CFactor));
%             app.CFactorEditField.Value=(topCValue/bottomCValue);       
            

            %Find corresponding wavelength value for the C Factor
            
            [m,~] = size(workingFile);
            maximillian=max(workingFile(:,4));
            i=1;
            flag=1;
            while(i<m && flag==1)
                if(maximillian==workingFile(i,2))
                    flag=workingFile(i,1);
                    i=m+1;
                end
                i=i+1;
            end
            
            [mm,~] = size(responsivity);
            i=1;
            flag2=1;
            while(i<mm && flag2==1 )
                if(round(100*responsivity(i,2)) == round(100*app.CFactor) && responsivity(i,1) > (flag-50))
                    flag2=i;
                end
                i=i+1;
            end
            
            if(flag2==1)
                flag2=flag;
            end
            
            
            %GRAPH!

            plot(app.UIAxes2,workingFile(:,1),workingFile(:,4),'linewidth',app.linewidth,'color','k');
            xlabel(app.UIAxes2,app.xlabel2,'FontSize',app.fontsize);
            ylabel(app.UIAxes2,app.ylabel2,'FontSize',app.fontsize);
%             ylim(app.UIAxes2,[-0.05 1]);
            xlim(app.UIAxes2,[i ii]);
            hold(app.UIAxes2,'on');
            yyaxis(app.UIAxes2,'right');
            plot(app.UIAxes2,interpResponsivity(:,1),interpResponsivity(:,2),'linewidth',app.linewidth,'color',[1 0.647 0]);
            plot(app.UIAxes2,flag2,app.CFactor,'.','MarkerSize',20,'Color','g');
            ylabel(app.UIAxes2,app.ylabel3,'FontSize',app.fontsize);
            ylim(app.UIAxes2,[-0.05 1]); 
            set(app.UIAxes2,'FontSize',app.tickLabelFontSize,'LineWidth',app.axisLineThickness)
            ax = app.UIAxes2;
            ax.TickDir = app.tickPosition;
            ax.FontName = app.fontType;
            set(app.UIAxes,'YColor',[1 0.647 0]);
            
            close all;
        end

        % Button pushed function: QEPulsingCalculationButton
        function QEPulsingCalculationButtonPushed(app, event)
            %Did you import data properly?
            
            if(iscell(app.pulsingData))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(iscell(app.pulsingBG))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(app.pulsingData==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(app.pulsingBG==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end


            %Reget all variables just in case            
            
            app.elecDiameter = app.ElectrodeDiametermEditField.Value;
            app.HValue = app.AtoEPSFactor*(app.PMTSens*1e-9); %Convert nA to A
            app.PMTSens = app.PMTSensnAVEditField.Value; %nA / V, normally is negative, but maybe not for 4687
            app.absorptionCorrectionFactor = app.AbsorptionCorrectionEditField.Value; %See paper for description
            app.pulsingCurrentCorrectionFactor = app.PulsingCurrentCorrectionFactorEditField.Value; %Default setting
            app.PMTCalibFactor = app.PMTCalibFactorEditField.Value; %Use this if below 25 uA
            app.opticalDensityOfFilter = app.OpticalDensityEditField.Value;
            app.elecReflectivity = (app.ElectrodeReflatMaxWaveEditField.Value)/100;
            app.CFactor = app.CFactorEditField.Value;


            %Necessary calculations
            
            app.HValue = app.AtoEPSFactor*(app.PMTSens*1e-9); %Convert A to nA
            AreaCorrectionFactor=(app.APMT)/(4*app.pi*(app.dPMT^2)); %This will be for a sphere for ECL
            app.Asource = ((app.elecDiameter/2)^2)*app.pi; %m^2 for Pt electrode
            app.ODFilterFactor=1/(10^-app.opticalDensityOfFilter);


            %Assign important datasets
            
            current=app.pulsingData(:,5);
            photocurrent=app.pulsingData(:,6);
            photocurrentBG=app.pulsingBG(:,6);
            photocurrent=photocurrent-photocurrentBG;
            potential=app.pulsingData(:,8);
            time=app.pulsingData(:,2);


            %Calculations
            
            PhotonFlux=app.ODFilterFactor*(photocurrent)*app.HValue*app.absorptionCorrectionFactor*(2-app.elecReflectivity)/(app.CFactor*app.PMTCalibFactor*AreaCorrectionFactor*app.gainFactor);
            Radiance=(PhotonFlux*app.EPhoton)/app.Asource;
            EQE=(PhotonFlux(:,1)*app.charge)./(current*app.pulsingCurrentCorrectionFactor); 
            Luminance=(PhotonFlux*app.KFactor)/app.Asource;
            CurrentEfficacy=(PhotonFlux(:,1)*app.KFactor)./(current*app.pulsingCurrentCorrectionFactor);
            LuminousEfficacy=(PhotonFlux*2*app.KFactor)./(current.*potential*app.pulsingCurrentCorrectionFactor); %2*pi for random directions!
            CurrentDensity=(current*app.pulsingCurrentCorrectionFactor)/app.Asource;
            current2=abs(current);
            current2=current2*app.pulsingCurrentCorrectionFactor;
            electronFlux=current./app.charge;


            %Total QE values

            [mmmmm,~]=size(current2);
            a=1;
            b=mmmmm;
            c=b-a;
            totalCharge=cumtrapz(time(a:c),current2(a:c));
            totalChargeValue=totalCharge(c,1);
            totalElectrons=totalChargeValue/app.charge;
            TotalPhotonRate=abs(PhotonFlux);
            totalPhotons=cumtrapz(time(a:c),TotalPhotonRate(a:c));
            totalPhotonsValue=totalPhotons(c,1);
            app.pulsingQE=totalPhotonsValue/totalElectrons*100;
            app.QEPulsingValueEditField.Value=app.pulsingQE;


            %Graph
            
            plot(app.UIAxes,time,PhotonFlux,'linewidth',app.linewidth,'color','k');
            xlabel(app.UIAxes,app.xlabel,'FontSize',app.fontsize);
            ylabel(app.UIAxes,app.ylabel,'FontSize',app.fontsize);
            [mmmmmm,~]=size(time);
            if(mmmmmm>1000)
                xlim(app.UIAxes,[0 time(1000,1)]);
            end
            hold(app.UIAxes,'on');
            yyaxis(app.UIAxes,'right');
            plot(app.UIAxes,time,electronFlux,'linewidth',app.linewidth,'color',[1 0.647 0]);
            ylabel(app.UIAxes,app.ylabel4,'FontSize',app.fontsize);
            set(app.UIAxes,'FontSize',app.tickLabelFontSize,'LineWidth',app.axisLineThickness)
            ax = app.UIAxes;
            ax.TickDir = app.tickPosition;
            ax.FontName = app.fontType;
            set(app.UIAxes,'YColor',[1 0.647 0]);
            
            close all;            
        end

        % Value changed function: QEPulsingValueEditField
        function QEPulsingValueEditFieldValueChanged(app, event)
            app.pulsingQE = app.QEPulsingValueEditField.Value;
        end

        % Value changed function: ElectrodeReflatMaxWaveEditField
        function ElectrodeReflatMaxWaveEditFieldValueChanged(app, event)
            app.elecReflectivity = app.ElectrodeReflatMaxWaveEditField.Value;
        end

        % Value changed function: PMTGainFactorEditField
        function PMTGainFactorEditFieldValueChanged(app, event)
            app.gainFactor = app.PMTGainFactorEditField.Value;
        end

        % Button pushed function: QECVCalculationButton
        function QECVCalculationButtonPushed(app, event)
            %Did you import data properly
            
            if(iscell(app.CVData))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(app.CVData==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end


            %Reget all variables just in case            
            
            app.elecDiameter = app.ElectrodeDiametermEditField.Value;
            app.PMTSens = app.PMTSensnAVEditField.Value; %nA / V, normally is negative, but maybe not for 4687
            app.absorptionCorrectionFactor = app.AbsorptionCorrectionEditField.Value; %See paper for description
            app.CVCurrentCorrectionFactor = app.CVCurrentCorrectionFactorEditField.Value; %Default setting
            app.PMTCalibFactor = app.PMTCalibFactorEditField.Value; %Use this if below 25 uA
            app.opticalDensityOfFilter = app.OpticalDensityEditField.Value;
            app.elecReflectivity = (app.ElectrodeReflatMaxWaveEditField.Value)/100;
            app.CFactor = app.CFactorEditField.Value;


            %Necessary calculations
            
            app.HValue = app.AtoEPSFactor*(app.PMTSens*1e-9); %Convert A to nA
            AreaCorrectionFactor=(app.APMT)/(4*app.pi*(app.dPMT^2)); %This will be for a sphere for ECL
            app.Asource = ((app.elecDiameter/2)^2)*app.pi; %m^2 for Pt electrode
            app.ODFilterFactor=1/(10^-app.opticalDensityOfFilter);


            %Assign important datasets
            
            current=app.CVData(:,3);
            photocurrent=app.CVData(:,7);
            photocurrent=photocurrent-min(photocurrent);
            potential=app.CVData(:,1);
            time=app.CVData(:,2);


            %Maybe a time modification
            
            if(time(1,1) ~=0)
                time(:,1)=time(:,1)-time(1,1);
            end


            %Calculations
            
            PhotonFlux=app.ODFilterFactor*(photocurrent)*app.HValue*app.absorptionCorrectionFactor*(2-app.elecReflectivity)/(app.CFactor*app.PMTCalibFactor*AreaCorrectionFactor*app.gainFactor);
            Radiance=(PhotonFlux*app.EPhoton)/app.Asource;
            EQE=(PhotonFlux(:,1)*app.charge)./(current*app.CVCurrentCorrectionFactor); 
            Luminance=(PhotonFlux*app.KFactor)/app.Asource;
            CurrentEfficacy=(PhotonFlux(:,1)*app.KFactor)./(current*app.CVCurrentCorrectionFactor);
            LuminousEfficacy=(PhotonFlux*2*app.KFactor)./(current.*potential*app.CVCurrentCorrectionFactor); %2*pi for random directions!
            CurrentDensity=(current*app.CVCurrentCorrectionFactor)/app.Asource;
            current2=abs(current);
            current2=current2*app.CVCurrentCorrectionFactor;
            electronFlux=current./app.charge;


            %Total QE values

            [mmmmm,~]=size(current2);
            a=1;
            b=mmmmm;
            c=b-a;
            totalCharge=cumtrapz(time(a:c),current2(a:c));
            totalChargeValue=totalCharge(c,1);
            totalElectrons=totalChargeValue/app.charge;
            TotalPhotonRate=abs(PhotonFlux);
            totalPhotons=cumtrapz(time(a:c),TotalPhotonRate(a:c));
            totalPhotonsValue=totalPhotons(c,1);
            app.CVQE=totalPhotonsValue/totalElectrons*100;
            app.QECVValueEditField.Value=app.CVQE;


            %Graph an appropriate amount of points (too many program crashes!)
            
            [mmmmmm,~]=size(time);
            aa=1;
            bb=mmmmmm;
%             if(mmmmmm>3000)
%                 bb=3000;
%             end
            
            plot(app.UIAxes,potential(aa:bb),PhotonFlux(aa:bb),'linewidth',app.linewidth,'color','k');
            xlabel(app.UIAxes,app.xlabel3,'FontSize',app.fontsize);
            ylabel(app.UIAxes,app.ylabel,'FontSize',app.fontsize);
            hold(app.UIAxes,'on');
            yyaxis(app.UIAxes,'right');
            plot(app.UIAxes,potential(aa:bb),electronFlux(aa:bb),'linewidth',app.linewidth,'color',[1 0.647 0]);
            ylabel(app.UIAxes,app.ylabel4,'FontSize',app.fontsize);
            set(app.UIAxes,'FontSize',app.tickLabelFontSize,'LineWidth',app.axisLineThickness)
            ax = app.UIAxes;
            ax.TickDir = app.tickPosition;
            ax.FontName = app.fontType;
            set(app.UIAxes,'YColor',[1 0.647 0]);
            
            close all; 
        end

        % Value changed function: CVCurrentCorrectionFactorEditField
        function CVCurrentCorrectionFactorEditFieldValueChanged(app, event)
            app.CVCurrentCorrectionFactor = app.CVCurrentCorrectionFactorEditField.Value;
        end

        % Button pushed function: CalculateCVCurrentCorrectionFactorButton
        function CalculateCVCurrentCorrectionFactorButtonPushed(app, event)
            %Did you import data properly?
            
            if(iscell(app.CVData))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(iscell(app.CVBG))
                msgbox('You have not modified the data needed for this software to work. Please remove all text from file.','ERROR: WHISKEY FOXTROT FOXTROT');
                return
            end
            if(app.CVData==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end
            if(app.CVBG==1)
                msgbox('You have not imported all the necessary data needed for this operation.','ERROR: NOVEMBER DELTA');
                return
            end


            %Get data necessary
            
            current=app.CVData(:,3);
            time=app.CVData(:,2);
            currentBG=app.CVBG(:,3);
            timeBG=app.CVBG(:,2);


            %Maybe a time modification
            
            if(time(1,1) ~=0)
                time(:,1)=time(:,1)-time(1,1);
            end


            %Perform data operations
            
            current=abs(current);
            currentBG=abs(currentBG);
            chargee=cumtrapz(time,current);
            chargeeBG=cumtrapz(timeBG,currentBG);
            [m,~]=size(chargee);
            [mm,~]=size(chargeeBG);
            chargeeMax=chargee(m,1);
            chargeeBGMax=chargeeBG(mm,1);            
            app.CVCurrentCorrectionFactor=1-((chargeeBGMax*2)/chargeeMax);


            %Final step
            app.CVCurrentCorrectionFactorEditField.Value = app.CVCurrentCorrectionFactor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.LoadPulsingDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadPulsingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPulsingDataButtonPushed, true);
            app.LoadPulsingDataButton.Text = 'Load Pulsing Data';
            app.LoadPulsingBackgroundButton = uibutton(app.ImportDataTab, 'push');
            app.LoadPulsingBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPulsingBackgroundButtonPushed, true);
            app.LoadPulsingBackgroundButton.Text = 'Load Pulsing Background';
            app.TextArea = uitextarea(app.ImportDataTab);
            app.TextArea.Value = {'Make sure that pulsing background and data are exactly the same in every respect, but have different potentials applied.'};
            app.TextArea_2 = uitextarea(app.ImportDataTab);
            app.TextArea_2.Value = {'Make sure that the CV background is exactly half of the total CV. Ex. If your scans were 1 V to -1 V, your background scans would be 0.5 V to -0.5 V.'};
            app.LoadCVDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadCVDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCVDataButtonPushed, true);
            app.LoadCVDataButton.Text = 'Load CV Data';
            app.LoadCVBackgroundDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadCVBackgroundDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCVBackgroundDataButtonPushed, true);
            app.LoadCVBackgroundDataButton.Text = 'Load CV Background Data';
            app.LoadPMTResponsivityDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadPMTResponsivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPMTResponsivityDataButtonPushed, true);
            app.LoadPMTResponsivityDataButton.Text = 'Load PMT Responsivity Data';
            app.LoadEmissionDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadEmissionDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEmissionDataButtonPushed, true);
            app.LoadEmissionDataButton.Text = 'Load Emission Data';
            app.LoadEmissionBackgroundDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadEmissionBackgroundDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEmissionBackgroundDataButtonPushed, true);
            app.LoadEmissionBackgroundDataButton.Text = 'Load Emission Background Data';
            app.TextArea_4 = uitextarea(app.ImportDataTab);
            app.TextArea_4.Value = {'Background emission spectra must be the same as the emission data loaded.'};
            app.LoadCCDCameraResponsivityDataButton = uibutton(app.ImportDataTab, 'push');
            app.LoadCCDCameraResponsivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCCDCameraResponsivityDataButtonPushed, true);
            app.LoadCCDCameraResponsivityDataButton.Text = 'Load CCD Camera Responsivity Data';
            app.ValuesTab = uitab(app.TabGroup);
            app.ValuesTab.Title = 'Values';
            app.OpticalDensityEditFieldLabel = uilabel(app.ValuesTab);
            app.OpticalDensityEditFieldLabel.Text = 'Optical Density';
            app.OpticalDensityEditField = uieditfield(app.ValuesTab, 'numeric');
            app.OpticalDensityEditField.ValueChangedFcn = createCallbackFcn(app, @OpticalDensityEditFieldValueChanged, true);
            app.OpticalDensityEditField.Value = 2;
            app.ElectrodeDiametermEditFieldLabel = uilabel(app.ValuesTab);
            app.ElectrodeDiametermEditFieldLabel.Text = 'Electrode Diameter (m)';
            app.ElectrodeDiametermEditField = uieditfield(app.ValuesTab, 'numeric');
            app.ElectrodeDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodeDiametermEditFieldValueChanged, true);
            app.ElectrodeDiametermEditField.Value = 0.003;
            app.PMTSensnAVEditFieldLabel = uilabel(app.ValuesTab);
            app.PMTSensnAVEditFieldLabel.Text = 'PMT Sens. (nA/V)';
            app.PMTSensnAVEditField = uieditfield(app.ValuesTab, 'numeric');
            app.PMTSensnAVEditField.ValueChangedFcn = createCallbackFcn(app, @PMTSensnAVEditFieldValueChanged, true);
            app.PMTSensnAVEditField.Value = 10000;
            app.PMTCalibFactorEditFieldLabel = uilabel(app.ValuesTab);
            app.PMTCalibFactorEditFieldLabel.Text = 'PMT Calib. Factor';
            app.PMTCalibFactorEditField = uieditfield(app.ValuesTab, 'numeric');
            app.PMTCalibFactorEditField.ValueChangedFcn = createCallbackFcn(app, @PMTCalibFactorEditFieldValueChanged, true);
            app.PMTCalibFactorEditField.Value = 0.1732;
            app.PulsingCurrentCorrectionFactorEditFieldLabel = uilabel(app.ValuesTab);
            app.PulsingCurrentCorrectionFactorEditFieldLabel.Text = 'Pulsing Current Correction Factor';
            app.PulsingCurrentCorrectionFactorEditField = uieditfield(app.ValuesTab, 'numeric');
            app.PulsingCurrentCorrectionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @PulsingCurrentCorrectionFactorEditFieldValueChanged, true);
            app.PulsingCurrentCorrectionFactorEditField.Value = 0.91;
            app.CalculatePulsingCurrentCorrectionFactorButton = uibutton(app.ValuesTab, 'push');
            app.CalculatePulsingCurrentCorrectionFactorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePulsingCurrentCorrectionFactorButtonPushed, true);
            app.CalculatePulsingCurrentCorrectionFactorButton.Text = 'Calculate Pulsing Current Correction Factor';
            app.TextArea_3 = uitextarea(app.ValuesTab);
            app.TextArea_3.Value = {'PMT Calibration Factor should be in units "calibrated signal / uncalibrated signal". Ex. If an LED with a certain photons/sr/s gives a calibrated signal of 10 and an uncalibrated signal of 1, then the calibration factor would be 10.'};
            app.AbsorptionCorrectionEditFieldLabel = uilabel(app.ValuesTab);
            app.AbsorptionCorrectionEditFieldLabel.Text = 'Absorption Correction';
            app.AbsorptionCorrectionEditField = uieditfield(app.ValuesTab, 'numeric');
            app.AbsorptionCorrectionEditField.Value = 1.1952;
            app.CalculateCFactorButton = uibutton(app.ValuesTab, 'push');
            app.CalculateCFactorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCFactorButtonPushed, true);
            app.CalculateCFactorButton.Text = 'Calculate C Factor';
            app.CFactorEditFieldLabel = uilabel(app.ValuesTab);
            app.CFactorEditFieldLabel.Text = 'C Factor';
            app.CFactorEditField = uieditfield(app.ValuesTab, 'numeric');
            app.CFactorEditField.ValueChangedFcn = createCallbackFcn(app, @CFactorEditFieldValueChanged, true);
            app.CFactorEditField.Value = 0.1373;
            app.ElectrodeReflatMaxWaveEditFieldLabel = uilabel(app.ValuesTab);
            app.ElectrodeReflatMaxWaveEditFieldLabel.Text = 'Electrode Refl. at Max. Wave (%)';
            app.ElectrodeReflatMaxWaveEditField = uieditfield(app.ValuesTab, 'numeric');
            app.ElectrodeReflatMaxWaveEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodeReflatMaxWaveEditFieldValueChanged, true);
            app.ElectrodeReflatMaxWaveEditField.Value = 60;
            app.PMTSurfaceaream2EditFieldLabel = uilabel(app.ValuesTab);
            app.PMTSurfaceaream2EditFieldLabel.Text = 'PMT Surface area (m^2)';
            app.PMTSurfaceaream2EditField = uieditfield(app.ValuesTab, 'numeric');
            app.PMTSurfaceaream2EditField.Value = 0.000192;
            app.ElectrodeDistancefromPMTmEditFieldLabel = uilabel(app.ValuesTab);
            app.ElectrodeDistancefromPMTmEditFieldLabel.Text = 'Electrode Distance from PMT (m)';
            app.ElectrodeDistancefromPMTmEditField = uieditfield(app.ValuesTab, 'numeric');
            app.ElectrodeDistancefromPMTmEditField.Value = 0.0716;
            app.PMTGainFactorEditFieldLabel = uilabel(app.ValuesTab);
            app.PMTGainFactorEditFieldLabel.Text = 'PMT Gain Factor';
            app.PMTGainFactorEditField = uieditfield(app.ValuesTab, 'numeric');
            app.PMTGainFactorEditField.ValueChangedFcn = createCallbackFcn(app, @PMTGainFactorEditFieldValueChanged, true);
            app.PMTGainFactorEditField.Value = 1300000;
            app.CVCurrentCorrectionFactorEditFieldLabel = uilabel(app.ValuesTab);
            app.CVCurrentCorrectionFactorEditFieldLabel.Text = 'CV Current Correction Factor';
            app.CVCurrentCorrectionFactorEditField = uieditfield(app.ValuesTab, 'numeric');
            app.CVCurrentCorrectionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @CVCurrentCorrectionFactorEditFieldValueChanged, true);
            app.CVCurrentCorrectionFactorEditField.Value = 0.5;
            app.CalculateCVCurrentCorrectionFactorButton = uibutton(app.ValuesTab, 'push');
            app.CalculateCVCurrentCorrectionFactorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCVCurrentCorrectionFactorButtonPushed, true);
            app.CalculateCVCurrentCorrectionFactorButton.Text = 'Calculate CV Current Correction Factor';
            app.QECalcTab = uitab(app.TabGroup);
            app.QECalcTab.Title = 'QE Calc';
            app.QEPulsingCalculationButton = uibutton(app.QECalcTab, 'push');
            app.QEPulsingCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @QEPulsingCalculationButtonPushed, true);
            app.QEPulsingCalculationButton.Text = 'QE Pulsing Calculation';
            app.QEPulsingValueEditFieldLabel = uilabel(app.QECalcTab);
            app.QEPulsingValueEditFieldLabel.Text = 'QE Pulsing Value';
            app.QEPulsingValueEditField = uieditfield(app.QECalcTab, 'numeric');
            app.QEPulsingValueEditField.ValueChangedFcn = createCallbackFcn(app, @QEPulsingValueEditFieldValueChanged, true);
            app.QECVCalculationButton = uibutton(app.QECalcTab, 'push');
            app.QECVCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @QECVCalculationButtonPushed, true);
            app.QECVCalculationButton.Text = 'QE CV Calculation';
            app.QECVValueEditFieldLabel = uilabel(app.QECalcTab);
            app.QECVValueEditFieldLabel.Text = 'QE CV Value';
            app.QECVValueEditField = uieditfield(app.QECalcTab, 'numeric');
            app.TextArea_5 = uitextarea(app.QECalcTab);
            app.TextArea_5.Value = {'Units of QE are in photons emitted per injected electron as a percentage. Ex. 5.012 in the box above is equal to 5.012 % of injected electrons produce a photon.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QE_app_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end